classdef Diffuser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiffuserGUI                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportdataTab                   matlab.ui.container.Tab
        Image_1                         matlab.ui.control.Image
        text11                          matlab.ui.control.Label
        RandomButton                    matlab.ui.control.Button
        ClipboardButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CleartableButton                matlab.ui.control.Button
        SelectfileButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        DeconvolutionoptionalTab        matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Image_2                         matlab.ui.control.Image
        BeamsizePanel                   matlab.ui.container.Panel
        UnitDropDown                    matlab.ui.control.DropDown
        UnitDropDownLabel               matlab.ui.control.Label
        FWHMLorentzEditField            matlab.ui.control.NumericEditField
        FWHMLorentzEditFieldLabel       matlab.ui.control.Label
        FWHMGaussEditField              matlab.ui.control.NumericEditField
        FWHMGaussEditFieldLabel         matlab.ui.control.Label
        FWHMEditField                   matlab.ui.control.NumericEditField
        FWHMEditFieldLabel              matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        fullwidthathalfmaximiumLabel    matlab.ui.control.Label
        DeconvolutionismodifiedfromLabel  matlab.ui.control.Label
        DeconvoluteanalyticalbeameffectsCheckBox  matlab.ui.control.CheckBox
        BeamshapeButtonGroup            matlab.ui.container.ButtonGroup
        VoigtButton                     matlab.ui.control.ToggleButton
        LorentzButton                   matlab.ui.control.ToggleButton
        GaussButton                     matlab.ui.control.ToggleButton
        RectangleButton                 matlab.ui.control.ToggleButton
        CircularButton                  matlab.ui.control.ToggleButton
        DiffusionmodelingTab            matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        XunitDropDown                   matlab.ui.control.DropDown
        XunitDropDownLabel              matlab.ui.control.Label
        CalculateDtButton               matlab.ui.control.Button
        StartmodelingButton             matlab.ui.control.Button
        InitialcompositionsoptionalPanel  matlab.ui.container.Panel
        SeeC1C2andC3definitionsButton   matlab.ui.control.Button
        steplengthEditField             matlab.ui.control.NumericEditField
        steplengthEditFieldLabel        matlab.ui.control.Label
        InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel  matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        FixedC3CheckBox                 matlab.ui.control.CheckBox
        FixedC2CheckBox                 matlab.ui.control.CheckBox
        C3EditField                     matlab.ui.control.NumericEditField
        C2EditField                     matlab.ui.control.NumericEditField
        C1EditField                     matlab.ui.control.NumericEditField
        AflatpeaktroughcanbeseeninprofileILCheckBox  matlab.ui.control.CheckBox
        FixedC1CheckBox                 matlab.ui.control.CheckBox
        mediumCusedinprofileKLLabel     matlab.ui.control.Label
        maxCLabel                       matlab.ui.control.Label
        minCLabel                       matlab.ui.control.Label
        DiffusionprofileButtonGroup     matlab.ui.container.ButtonGroup
        LButton                         matlab.ui.control.ToggleButton
        KButton                         matlab.ui.control.ToggleButton
        JButton                         matlab.ui.control.ToggleButton
        IButton                         matlab.ui.control.ToggleButton
        HButton                         matlab.ui.control.ToggleButton
        GButton                         matlab.ui.control.ToggleButton
        FButton                         matlab.ui.control.ToggleButton
        EButton                         matlab.ui.control.ToggleButton
        DButton                         matlab.ui.control.ToggleButton
        CButton                         matlab.ui.control.ToggleButton
        BButton                         matlab.ui.control.ToggleButton
        AButton                         matlab.ui.control.ToggleButton
        TimecalculationTab              matlab.ui.container.Tab
        DiffusionCoefficientDPanel      matlab.ui.container.Panel
        CalculatetwithD0andEaerrorsCheckBox  matlab.ui.control.CheckBox
        Reference                       matlab.ui.control.DropDown
        Element                         matlab.ui.control.DropDown
        ButtonGroup                     matlab.ui.container.ButtonGroup
        UseaconstantDButton             matlab.ui.control.RadioButton
        UseArrheniusequationButton      matlab.ui.control.RadioButton
        UITable2                        matlab.ui.control.Table
        ExamplesDropDown                matlab.ui.control.DropDown
        ExamplesDropDownLabel           matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        InitialtemperatureEditField     matlab.ui.control.NumericEditField
        InitialtemperatureEditFieldLabel  matlab.ui.control.Label
        TrialsforMonteCarloEditField    matlab.ui.control.NumericEditField
        TrialsforMonteCarloEditFieldLabel  matlab.ui.control.Label
        TemperatureerrorEditField       matlab.ui.control.NumericEditField
        TemperatureerrorEditFieldLabel  matlab.ui.control.Label
        CoolingpathPanel                matlab.ui.container.Panel
        CoolingrateisknownCheckBox      matlab.ui.control.CheckBox
        UITable3                        matlab.ui.control.Table
        OptionsDropDown                 matlab.ui.control.DropDown
        OptionsDropDownLabel            matlab.ui.control.Label
        CalculatetButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        sigmaLabel                      matlab.ui.control.Label
        DcalculationTab                 matlab.ui.container.Tab
        Image_5                         matlab.ui.control.Image
        lnD1TcurvefittingPanel          matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        CurvefitButton                  matlab.ui.control.Button
        UITable5                        matlab.ui.control.Table
        lnDTdataimportPanel             matlab.ui.container.Panel
        LoadexampleButton               matlab.ui.control.Button
        CleartableButton_2              matlab.ui.control.Button
        ClipboardButton_2               matlab.ui.control.Button
        SelectfileButton_2              matlab.ui.control.Button
        UITable4                        matlab.ui.control.Table
        DcalculationforexperimentalstudyPanel  matlab.ui.container.Panel
        CalculateDButton                matlab.ui.control.Button
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
    end


    properties (Access = private)
        count
        data
        name
        DiffCoef
        outputname
        output
    end

    methods (Access = private)

        function Readclipdata(app)
            %read clipboard data
            try
                tmp=getclipdata(1);
            catch
                uialert(app.DiffuserGUI, 'Fail to use clipboard with java on this computer','')
                return
            end
            col=size(tmp,2);
            if col~=2 && col~=3
                uialert(app.DiffuserGUI,'Input data should have 2 or 3 columns','')
                return
            end
            app.data=tmp;
            app.UITable.Data=app.data;
            app.UITable.ColumnFormat={'numeric'};

            x = app.data(:,1);
            C = app.data(:,2);
            [~,I]=sort(x);
            app.data=app.data(I,:);

            if col==3
                err = app.data(:,3);
                h=errorbar(app.UIAxes, x, C, err);
                h.Marker='.';
                h.MarkerSize=12;
                h.LineStyle='none';
                h.CapSize=0;
            else
                plot(app.UIAxes, x, C, '-o');
            end
        end

        function [name, data] = readDiffCoef(app)
            str='DiffusionCoefficient.xlsx';
            if exist(str,'file')
                sheets = sheetnames(str);
                cs=length(sheets);
                if cs==1
                    sheet=sheets{1};
                    v=1;
                else
                    [st,v]=listdlg('PromptString','Select a sheet','SelectionMode','single','ListString',sheets);
                    sheet=sheets{st};
                end
                if v
                    raw=readcell(str,'Sheet',sheet);
                else
                    name=[]; data=[];
                    return
                end
            else
                uialert(app.DiffuserGUI, "'DiffusionCoefficient.xlsx' does not exist, please input diffusion coefficients manually.",'')
                name=[]; data=[];
                return
            end
            name=raw(2:end,1:3);
            data=raw(2:end,4:8);
            index=cellfun(@(x) isa(x,'missing'), data);
            data(index) = {[]};
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.count=1;
            app.UITable.Data=repmat({[]},10,3);
            app.UITable.ColumnFormat={'numeric'};
            [app.name, app.DiffCoef] = readDiffCoef(app);
            if ~isempty(app.DiffCoef)
                %set diffusion coefficients options
                app.ExamplesDropDown.Items=unique(app.name(:,1),'stable');
                mineral = app.ExamplesDropDown.Value;
                mineral_site=find(strcmp(app.name(:,1),mineral));
                elementlist=app.name(mineral_site,2);
                app.Element.Items=unique(elementlist,'stable');
                element=app.Element.Value;
                element_site=mineral_site(strcmp(elementlist,element));
                referencelist=app.name(element_site,3);
                app.Reference.Items=referencelist;
                reference=app.Reference.Value;
                reference_site=element_site(strcmp(referencelist,reference));

                app.UITable2.Data=app.DiffCoef(reference_site(1),:);
                app.UITable2.ColumnFormat={'numeric'};
            end
            app.UITable3.Data={0,'°C/myr','T=T0'};
            app.UITable3.ColumnFormat={'numeric',{'°C/myr','°C/kyr','°C/yr','°C/sec'},'char'};
            
            %Tooltip-------------------------------------------------------------------------------------------------------------
            %import data panel
            app.SelectfileButton.Tooltip='Import data from a spreadsheet or delimited text';
            app.RandomButton.Tooltip='Generate a random diffusion profile';
            app.CleartableButton.Tooltip='Clear the current table and plot';
            app.UITable.Tooltip=['The distance and composition data should be in the first two columns.', newline, ...
                'Composition uncertainties (σ) can be assigned as empty or in the third column.'];

            %deconvolution panel
            app.DeconvolutionoptionalTab.Tooltip='This is an optional function to deconvolute the possible analytical beam effect';
            app.DeconvoluteanalyticalbeameffectsCheckBox.Tooltip=['Tick to deconvolute the beam effect'];
            app.BeamshapeButtonGroup.Tooltip='Choose the analytical beam shape';
            app.BeamsizePanel.Tooltip=['Set the beam size and unit:', newline, ...
                'set diameter for a circular/elliptical beam shape; ', newline, ...
                'set width for a square/rectangular beam shape; ', newline, ...
                'set FWHM for a Gaussian or Lorentzian beam shape; ', newline, ...
                'set FWHM Gauss and FWHM Lorentz for a pseudo-Voigt beam shape.'];
            
            %diffusion modeling panel
            app.XunitDropDown.Tooltip='Choose the distance unit of the input diffusion profile';
            app.DiffusionprofileButtonGroup.Tooltip='Choose the exact shape of the input diffusion profile';
            app.InitialcompositionsoptionalPanel.Tooltip='This is an optional function to fix the initial composition, otherwise Diffuser will determine it automatically';
            app.FixedC1CheckBox.Tooltip='Tick to fix the minimum value of the initial composition';
            app.FixedC2CheckBox.Tooltip='Tick to fix the maximum value of the initial composition';
            app.FixedC3CheckBox.Tooltip='Tick to fix the medium plateau of the initial composition for profiles K-L (see definitions by the button above)';
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Tooltip=['Tick if an initial flat peak or trough can be seen for profiles I–L,', newline, ...
                'otherwise input the speculated range of the initial flat peak or trough below'];
            app.minEditField.Tooltip='input the speculated minimum value of the initial flat peak or trough';
            app.maxEditField.Tooltip='input the speculated maximum value of the initial flat peak or trough';
            app.steplengthEditField.Tooltip='input the step length between the min and max';
            app.StartmodelingButton.Tooltip='Click to start curve fitting of the input diffusion profile';
            app.CalculateDtButton.Tooltip='Output the Dt value calculated by curve fitting';
            
            %time calculation panel
            app.DiffusionCoefficientDPanel.Tooltip='Define the diffusion coefficient of an element in a mineral';
            app.UseArrheniusequationButton.Tooltip='Tick if D can be expressed as a simple Arrhenius equation by using an internally consistent database in Diffuser (accessible on github)';
            app.UseaconstantDButton.Tooltip='Tick if D is expressed as a constant';
            app.ExamplesDropDown.Tooltip='Choose the analyzed mineral';
            app.Element.Tooltip='Choose the analyzed element';
            app.Reference.Tooltip='Choose the reference of D which may be determined by different studies';
            app.CalculatetwithD0andEaerrorsCheckBox.Tooltip='Tick to propagate D0 and E uncertainties (or D uncertainty if using a constant D) into timescale uncertainties';
            app.UITable2.Tooltip='Input the D0 and E parameters (automatically filled if using the internal database), or D parameters if using a constant D';
            app.InitialtemperatureEditField.Tooltip='Input the initial temperature when the diffusion starts';
            app.TemperatureerrorEditField.Tooltip='Input the error of the initial temperature';
            app.TrialsforMonteCarloEditField.Tooltip='Input the trials for Monte Carlo modeling in calculating the timescale and its uncertainty';
            app.CoolingpathPanel.Tooltip='Set the cooling path for the diffusion process, e.g., isothermal, linear, exponential, or parabolic';
            app.UITable3.Tooltip='Input the parameters of the cooling path based on the equation form';
            app.CalculatetButton.Tooltip='Output the calculated diffusion timescale';
            app.CoolingrateisknownCheckBox.Tooltip='Choose whether the cooling rate is known';
            
            %D calculation panel
            app.DcalculationTab.Tooltip='This panel is useful for experimental studies to determine the diffusion coefficient and its covariance matrix';
            app.DcalculationforexperimentalstudyPanel.Tooltip='After Dt calculation in Diffusion modeling panel, get the D value by inputing the duration in isothermal experimental studies';
            app.TimeEditField.Tooltip='Input the duration of the isothermal experiment';
            app.CalculateDButton.Tooltip='Calculate D by dividing Dt by t';
            app.lnDTdataimportPanel.Tooltip='Import experimental T and ln[D] data to calculate the diffusion coefficient and its covariance matrix';
            app.SelectfileButton_2.Tooltip='Import data from a spreadsheet or delimited text';
            app.LoadexampleButton.Tooltip='Generate an example of experimental T and ln[D] data';
            app.CleartableButton_2.Tooltip='Clear the current table and plot';
            app.UITable4.Tooltip=['The temperature and ln[D] data should be in the first two columns.', newline, ...
                'ln[D] uncertainties can be assigned as empty or in the third column,'];
            app.CurvefitButton.Tooltip='Calculate the diffusion coefficient and its covariance matrix by curve fitting using the input T and ln[D] data';
            app.SaveButton.Tooltip='Download the diffusion coefficient and its covariance matrix';
            app.UITable5.Tooltip='This table shows the calculated diffusion coefficient and its covariance matrix';
            
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [filename, pathname]=uigetfile({'*.xlsx;*.xls;*.csv;*.txt'},'Select a file','MultiSelect','off');
            if ~pathname
                return
            end
            str=fullfile(pathname,filename);
            [~,~,extension]=fileparts(filename);
            switch extension
                case {'.txt','.csv'}
                    tmp=readmatrix(str);
                    if size(tmp,2)>2
                        prompt={'Distance column (A,B,C etc)','Composition column','Uncertainty column (optional)'};
                        dlgtitle='Input';
                        dims=[1 35];
                        definput={'A','B','C'};
                        answer=inputdlg(prompt,dlgtitle,dims,definput);
                        data0(:,1)=readmatrix(str,'Range',strcat(answer{1},':',answer{1}));
                        data0(:,2)=readmatrix(str,'Range',strcat(answer{2},':',answer{2}));
                        if ~isempty(answer{3})
                            data0(:,3)=readmatrix(str,'Range',strcat(answer{3},':',answer{3}));
                        end
                        app.data=data0;
                    else
                        app.data=tmp;
                    end
                case {'.xlsx','.xls'}
                    sheets = sheetnames(str);
                    cs=length(sheets);
                    if cs==1
                        sheet=sheets{1};
                        v=1;
                    else
                        [st,v]=listdlg('PromptString','Select a sheet','SelectionMode','single','ListString',sheets);
                        sheet=sheets{st};
                    end
                    if v
                        tmp=readmatrix(str,'Sheet',sheet);
                        if size(tmp,2)>2
                            prompt={'Distance column (A,B,C etc)','Composition column','Uncertainty column (optional)'};
                            dlgtitle='Input';
                            dims=[1 35];
                            definput={'A','B','C'};
                            answer=inputdlg(prompt,dlgtitle,dims,definput);
                            data0(:,1)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{1},':',answer{1}));
                            data0(:,2)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{2},':',answer{2}));
                            if ~isempty(answer{3})
                                data0(:,3)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{3},':',answer{3}));
                            end
                            app.data=data0;
                        else
                            app.data=tmp;
                        end
                    end
                otherwise
                    return
            end

            % delete the first non-numeric rows
            for i=1:size(app.data,1)
                if  isnan(app.data(1,:))
                    app.data(1,:)=[];
                else
                    break
                end
            end

            if size(app.data,2)==3
                app.UITable.Data=app.data;
                h=errorbar(app.UIAxes, app.data(:,1), app.data(:,2), app.data(:,3));
                h.Marker='.';
                h.MarkerSize=12;
                h.LineStyle='none';
                h.CapSize=0;
            elseif size(app.data,2)==2
                app.UITable.Data=app.data;
                plot(app.UIAxes, app.data(:,1), app.data(:,2), '-o');
            else
                uialert(app.DiffuserGUI,'Input data should have 2 or 3 columns','')
                return
            end
            app.UITable.ColumnFormat={'numeric'};

            [~,I]=sort(app.data(:,1));
            app.data=app.data(I,:);
        end

        % Button pushed function: ClipboardButton
        function ClipboardButtonPushed(app, event)
            Readclipdata(app);
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            C1=30; C2=100; C3=70;
            Dt=1e-10;
            h=20*1e-6;
            switch mod(app.count,12)
                case 1
                    x=(0:3:60)'*1e-6;
                    C=(C2-C1)*erf(x/2/sqrt(Dt))+C1;
                    x=x+15e-6;
                case 2
                    x=(-60:3:0)'*1e-6;
                    C=(C2-C1)*erf(-x/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                case 3
                    x=(0:3:60)'*1e-6;
                    C=(C2-C1)*erfc(x/2/sqrt(Dt))+C1;
                    x=x+15e-6;
                case 4
                    x=(-60:3:0)'*1e-6;
                    C=(C2-C1)*erfc(-x/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                case 5
                    h=60*1e-6;
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)*(erf((h+x)/2/sqrt(Dt))+erf((h-x)/2/sqrt(Dt))-1)+C1;
                    x=x+75e-6;
                case 6
                    h=60*1e-6;
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)*(erfc((h+x)/2/sqrt(Dt))+erfc((h-x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 7
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(1+erf((x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 8
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*erfc((x)/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                case 9
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(erf((h-x)/2/sqrt(Dt))+erf((h+x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 10
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(erfc((h-x)/2/sqrt(Dt))+erfc((h+x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 11
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C3)/2*erf((h-x)/2/sqrt(Dt))+(C2-C1)/2*erf((h+x)/2/sqrt(Dt))+(C1+C3)/2;
                    x=x+75e-6;
                case 0
                    x=(-60:3:60)'*1e-6;
                    C=(C3-C1)/2*erfc((h-x)/2/sqrt(Dt))+(C2-C1)/2*erfc((h+x)/2/sqrt(Dt))+C1;
                    x=x+75e-6;
            end
            temp=rand(length(x),1)*2-1; %[-1,1]
            C=C+temp*3;
            app.data=[x,C];
            app.UITable.Data=app.data;
            app.XunitDropDown.Value='m';
            plot(app.UIAxes, x, C, '-o');
            app.count=app.count+1;
        end

        % Button pushed function: CleartableButton
        function CleartableButtonPushed(app, event)
            app.UITable.Data=repmat({[]},10,3);
            cla(app.UIAxes)
        end

        % Selection changed function: DiffusionprofileButtonGroup
        function DiffusionprofileButtonGroupSelectionChanged(app, event)
            selectedButton = app.DiffusionprofileButtonGroup.SelectedObject;
            profiletype = selectedButton.Text;
            if contains(profiletype, {'I','J'})
                app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable='on';
                app.FixedC1CheckBox.Enable='on';
                app.C1EditField.Enable='on';
                app.FixedC2CheckBox.Enable='on';
                app.C2EditField.Enable='on';
                app.FixedC3CheckBox.Value=0;
                app.FixedC3CheckBox.Enable='off';
                app.C3EditField.Enable='off';
            elseif contains(profiletype, {'K','L'})
                app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable='on';
                app.FixedC1CheckBox.Enable='on';
                app.C1EditField.Enable='on';
                app.FixedC2CheckBox.Enable='on';
                app.C2EditField.Enable='on';
                app.FixedC3CheckBox.Enable='on';
                app.C3EditField.Enable='on';
            else
                app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable='off';
                app.FixedC1CheckBox.Enable='on';
                app.C1EditField.Enable='on';
                app.FixedC2CheckBox.Enable='on';
                app.C2EditField.Enable='on';
                app.FixedC3CheckBox.Value=0;
                app.FixedC3CheckBox.Enable='off';
                app.C3EditField.Enable='off';
            end
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Value=1;
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable='off';
            app.minEditField.Enable='off';
            app.minEditFieldLabel.Enable='off';
            app.maxEditField.Enable='off';
            app.maxEditFieldLabel.Enable='off';
            app.steplengthEditField.Enable='off';
            app.steplengthEditFieldLabel.Enable='off';
        end

        % Button pushed function: StartmodelingButton
        function StartmodelingButtonPushed(app, event)
            global log10Dt elog10Dt C_data
            %prepare diffusion data-----------------------------------------
            if isempty(app.data)
                return
            end
            selectedButton = app.DiffusionprofileButtonGroup.SelectedObject;
            profiletype = selectedButton.Text;
            xunit=app.XunitDropDown.Value;
            col=size(app.data,2);
            x = app.data(:,1);
            C = app.data(:,2);
            if col==3
                err=app.data(:,3);
            else
                err=ones(length(x),1);
            end
            weights=1./(err.^2);
            switch xunit
                case 'nm'
                    factor=1e9;
                case 'um'
                    factor=1e6;
                case 'mm'
                    factor=1e3;
                case 'm'
                    factor=1;
            end
            x=x/factor;
            %prepare deconvolution-----------------------------------------
            deconvolution=app.DeconvoluteanalyticalbeameffectsCheckBox.Value;
            if deconvolution
                beamunit=app.UnitDropDown.Value;
                switch beamunit
                    case 'nm'
                        f=1e9;
                    case 'um'
                        f=1e6;
                    case 'mm'
                        f=1e3;
                    case 'm'
                        f=1;
                end
                try
                    if app.CircularButton.Value
                        width=app.DiameterEditField.Value/f; %circular, elliptical beam
                        dx=min([width/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/2:dx:max(x)+(max(x)-min(x))/2;
                        [X,Y] = meshgrid(0:dx:width,0:dx:width);
                        centre=[mean(mean(X,1)) mean(mean(Y,2))];
                        dist=sqrt(abs(X-centre(1)).^2+abs(Y-centre(2)).^2);
                        dist(dist>width/2)=NaN;
                        dist(~isnan(dist))=1;
                        dist(isnan(dist))=0;
                        proportions=sum(dist)./(sum(sum(dist)));
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.RectangleButton.Value
                        width=app.WidthEditField.Value/f; %rectangle, square beam
                        dx=min([width/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        n_conv=round(width/mean(diff(model_x)));
                        proportions=ones(1,n_conv);
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.GaussButton.Value
                        FWHM=app.FWHMEditField.Value/f; %full width at half maximum; Gaussian beam
                        dx=min([FWHM/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        sigma=FWHM/2.355;
                        proportions=exp(-((model_x-mean(model_x)).^2./(2.*sigma.^2)));
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.LorentzButton.Value
                        FWHM=app.FWHMEditField.Value/f; %full width at half maximum; Lorentzian beam
                        dx=min([FWHM/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        proportions=(1./pi).*((0.5.*FWHM)./((model_x-mean(model_x)).^2+(0.5.*FWHM).^2));
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.VoigtButton.Value
                        FWHM_L=app.FWHMLorentzEditField.Value/f; FWHM_G=app.FWHMGaussEditField.Value/f; %full width at half maximum; Voigt beam
                        FWHM=(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)); %get FWHM effective for voigt
                        dx=min([FWHM/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        proportions=((1.36603*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))-0.47719*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^2+0.11116*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^3)*((1./(pi*(0.5.*(FWHM_L+1e-10)))).*(((0.5.*(FWHM_L+1e-10))^2)./((model_x-mean(model_x)).^2+(0.5.*(FWHM_L+1e-10)).^2)))+(1-(1.36603*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))-0.47719*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^2+0.11116*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^3))*((1/(((FWHM_G+1e-10)/2.355)*sqrt(2*pi)))*exp(-0.5.*((model_x-mean(model_x))./((FWHM_G+1e-10)/2.355)).^2)));
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                catch
                    uialert(app.DiffuserGUI,'Please check the unit of beam size and distance','');
                    return
                end
            else
                model_x=[];
                props_matrix=[];
                pmsum=[];
            end

            %initial concentration-----------------------------------------
            fixC1=app.FixedC1CheckBox.Value;
            fixC2=app.FixedC2CheckBox.Value;
            fixC3=app.FixedC3CheckBox.Value;
            if fixC1
                C1 = app.C1EditField.Value;
            else
                C1=[];
            end
            if fixC2
                C2 = app.C2EditField.Value;
            else
                C2=[];
            end
            if fixC3
                C3 = app.C3EditField.Value;
            else
                C3=[];
            end

            %             try
            if contains(profiletype, {'I','J','K','L'})
                [log10Dt, elog10Dt, paras, C_data] = solution_IL(x, C, C1, C2, C3, profiletype, weights, factor, app, model_x, props_matrix, pmsum);
            else
                C_data=[];
                [log10Dt, elog10Dt, paras] = solution_AH(x, C, C1, C2, profiletype, weights, factor, app, model_x, props_matrix, pmsum);
            end
            if isempty(log10Dt)
                return
            end
            if isnan(log10Dt)
                msgbox('No fitting results can be found, please check input parameters')
                return
            end

            %             catch exc
            %                 errorMsg=exc.message;
            %                 uialert(app.Diffuser,errorMsg,'');
            %             end

            %--------------------------------------------------------------
            N=1:1:length(log10Dt); N=N';
            if contains(profiletype, {'E','F'})
                %paras=R2; C1; C2; x0; log10(Dt); h
                app.output=[N, paras(:,[1:4,6]), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'x0 (m)', 'rim-to-core length (m)', 'log10[Dt]', '2s'};
            elseif contains(profiletype, {'I','J'})
                %paras=R2; C1; C2; x0; log10(Dt); h
                app.output=[N, paras(:,[1:4,6]), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'x0 (m)', 'half band width (m)', 'log10[Dt]', '2s'};
            elseif contains(profiletype, {'K','L'})
                %paras=R2; C1; C2; x0; log10(Dt); h; C3
                app.output=[N, paras(:,[1:3,7,4,6]), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'C3', 'x0 (m)', 'half band width (m)', 'log10[Dt]', '2s'};
            else
                %paras=R2; C1; C2; x0; log10(Dt)
                app.output=[N, paras(:,1:4), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'x0 (m)', 'log10[Dt]', '2s'};
            end
            %             disp(['log10[Dt]=',num2str(log10Dt,'%.4f'),'±',num2str(elog10Dt,'%.4f')])
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            coolingpath = app.OptionsDropDown.Value;
            switch coolingpath
                case 'isothermal'
                    app.UITable3.ColumnName={'Constant (A)','Unit of A','Equation form'};
                    app.UITable3.Data={0,'°C/myr','T=T0'};
                    app.UITable3.ColumnEditable=[false false false];
                    app.UITable3.ColumnFormat={'numeric',{'°C/myr','°C/kyr','°C/yr','°C/sec'},'char'};
                    app.CoolingrateisknownCheckBox.Enable='off';
                case 'linear cooling'
                    app.UITable3.ColumnName={'Constant (A)','Unit of A','Equation form'};
                    app.UITable3.Data={0,'°C/myr','T=T0-At'};
                    app.UITable3.ColumnEditable=[true true false];
                    app.UITable3.ColumnFormat={'numeric',{'°C/myr','°C/kyr','°C/yr','°C/sec'},'char'};
                    app.CoolingrateisknownCheckBox.Enable='on';
                case 'exponential cooling'
                    app.UITable3.ColumnName={'Constant (A)','Unit of A','Equation form'};
                    app.UITable3.Data={0,'1/myr','T=T0*exp(-At)'};
                    app.UITable3.ColumnEditable=[true true false];
                    app.UITable3.ColumnFormat={'numeric',{'1/myr','1/kyr','1/yr','1/sec'},'char'};
                    app.CoolingrateisknownCheckBox.Enable='on';
                case 'parabolic cooling'
                    app.UITable3.ColumnName={'Constant (A)','Unit of A','Equation form'};
                    app.UITable3.Data={0,'°C/[myr^2]','T=T0-At^2'};
                    app.UITable3.ColumnEditable=[true true false];
                    app.UITable3.ColumnFormat={'numeric',{'°C/[myr^2]','°C/[kyr^2]','°C/[yr^2]','°C/[sec^2]'},'char'};
                    app.CoolingrateisknownCheckBox.Enable='on';
                case 'user defined'
                    app.UITable3.ColumnName={'User defined','Unit of T and t','Equation form'};
                    app.UITable3.Data={'Expressed as T=f(T0,t)','T is in K, t is in s','T=T0-5e-20*t^2'};
                    app.UITable3.ColumnEditable=[false true true];
                    app.UITable3.ColumnFormat={'char','char','char'};
            end
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            mineral = app.ExamplesDropDown.Value;
            mineral_site=find(strcmp(app.name(:,1),mineral));
            elementlist=app.name(mineral_site,2);
            app.Element.Items=unique(elementlist,'stable');
            element=app.Element.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name(element_site,3);
            app.Reference.Items=referencelist;
            reference=app.Reference.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable2.Data=app.DiffCoef(reference_site(1),:);
            %             app.UITable2.ColumnFormat={'numeric'};
        end

        % Value changed function: Element
        function ElementValueChanged(app, event)
            mineral = app.ExamplesDropDown.Value;
            mineral_site=find(strcmp(app.name(:,1),mineral));
            elementlist=app.name(mineral_site,2);
            element=app.Element.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name(element_site,3);
            app.Reference.Items=referencelist;
            reference=app.Reference.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable2.Data=app.DiffCoef(reference_site(1),:);
        end

        % Value changed function: Reference
        function ReferenceValueChanged(app, event)
            mineral = app.ExamplesDropDown.Value;
            mineral_site=find(strcmp(app.name(:,1),mineral));
            elementlist=app.name(mineral_site,2);
            element=app.Element.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name(element_site,3);
            reference=app.Reference.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable2.Data=app.DiffCoef(reference_site(1),:);
        end

        % Button pushed function: CalculateDtButton
        function CalculateDtButtonPushed(app, event)
            global log10Dt elog10Dt
            %elog10Dt is 2sigma absolute error
            Dt=10.^log10Dt;
            eDt=log(10)*10.^log10Dt.*elog10Dt; %2 sigma absolute error
            Title=[app.outputname, {'Dt', '2s'}];
            outputdata=num2cell([app.output,Dt,eDt]);

            filename=['Dt result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM')];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    if verLessThan('matlab','9.10')
                        xlswrite(fullfile(PathName,FileName),[Title;outputdata]);
                    else
                        writecell([Title;outputdata],fullfile(PathName,FileName),'AutoFitWidth', 0);
                    end
                otherwise
                    return
            end
        end

        % Button pushed function: CalculateDButton
        function CalculateDButtonPushed(app, event)
            global log10Dt elog10Dt
            t=app.TimeEditField.Value;
            %elog10Dt is 2sigma absolute error
            log10D=log10Dt-log10(t);
            elog10D=elog10Dt;
            lnD=log10D*log(10);
            elnD=elog10D*log(10);
            D=10.^log10Dt/t;
            eD=log(10)*10.^log10Dt.*elog10Dt/t; %2 sigma absolute error
            Title=[app.outputname, {'log10[D]', '2s', 'ln[D]', '2s', 'D', '2s'}];
            tmp=[log10D,elog10D,lnD,elnD,D,eD];
            outputdata=num2cell([app.output,tmp]);

            filename=['D result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM')];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    if verLessThan('matlab','9.10')
                        xlswrite(fullfile(PathName,FileName),[Title;outputdata]);
                    else
                        writecell([Title;outputdata],fullfile(PathName,FileName),'AutoFitWidth', 0);
                    end
                otherwise
                    return
            end
        end

        % Button pushed function: CalculatetButton
        function CalculatetButtonPushed(app, event)
            global log10Dt elog10Dt C_data
            if isnan(log10Dt)
                msgbox('No fitting results can be found')
                return
            end
            T = app.InitialtemperatureEditField.Value+273.15; %initial temperature, Celsius
            eT = app.TemperatureerrorEditField.Value; %initial temperature uncertainty, Celsius
            ttrials = app.TrialsforMonteCarloEditField.Value;
            Dtype = app.UseArrheniusequationButton.Value;
            withD0Eerror=app.CalculatetwithD0andEaerrorsCheckBox.Value;

            coolingpath = app.OptionsDropDown.Value;
            dT_dt = app.UITable3.Data{1}; %cooling rate
            rateunit=app.UITable3.Data{2};
            switch coolingpath
                case 'linear cooling'
                    switch rateunit
                        case '°C/myr'
                            dT_dt=dT_dt/1e6/365/24/3600;
                        case '°C/kyr'
                            dT_dt=dT_dt/1e3/365/24/3600;
                        case '°C/yr'
                            dT_dt=dT_dt/365/24/3600;
                        case '°C/sec'
                        otherwise
                            msgbox('Unknown unit of cooling constant')
                            return
                    end
                case 'exponential cooling'
                    switch rateunit
                        case '1/myr'
                            dT_dt=dT_dt/1e6/365/24/3600;
                        case '1/kyr'
                            dT_dt=dT_dt/1e3/365/24/3600;
                        case '1/yr'
                            dT_dt=dT_dt/365/24/3600;
                        case '1/sec'
                        otherwise
                            msgbox('Unknown unit of cooling constant')
                            return
                    end
                case 'parabolic cooling'
                    switch rateunit
                        case '°C/[myr^2]'
                            dT_dt=dT_dt/(1e6*365*24*3600)^2;
                        case '°C/[kyr^2]'
                            dT_dt=dT_dt/(1e3*365*24*3600)^2;
                        case '°C/[yr^2]'
                            dT_dt=dT_dt/(365*24*3600)^2;
                        case '°C/[sec^2]'
                        otherwise
                            msgbox('Unknown unit of cooling constant')
                            return
                    end
                case 'user defined'
                    dT_dt = 1; %not isothermal
            end

            is0=find(isnan(elog10Dt));
            if is0
                elog10Dt(is0)=0;
            end
            % elog10Dt=zeros(length(elog10Dt),1); %do not propagate D uncertainty
            if Dtype && ~strcmp(app.OptionsDropDown.Value,'isothermal') && ~app.CoolingrateisknownCheckBox.Value
                israte=1;
                [t, et, T_t, rate, erate, T_rate, error_contribution, isbreak] = solve_time_dependent_D_rate(log10Dt, elog10Dt, T, eT, ttrials, app.UITable2.Data, coolingpath, withD0Eerror, app);
            else
                israte=0;
                [t, et, T_t, error_contribution, isbreak] = solve_time_dependent_D(log10Dt, elog10Dt, T, eT, dT_dt, ttrials, app.UITable2.Data, coolingpath, Dtype, withD0Eerror, app);
            end


            if isnan(t) | isbreak
                return
            end
            %define time unit----------------------------------------------------
            if t(end,end)>(1e6*365*24*3600) %myr
                fc=1e6*365*24*3600;
                tname='Timescale (myr)';
            elseif t(end,end)>(1e3*365*24*3600) %kyr
                fc=1e3*365*24*3600;
                tname='Timescale (kyr)';
            elseif t(end,end)>(365*24*3600) %year
                fc=365*24*3600;
                tname='Timescale (year)';
            elseif t(end,end)>(24*3600) %day
                fc=24*3600;
                tname='Timescale (day)';
            elseif t(end,end)>3600 %hour
                fc=3600;
                tname='Timescale (hour)';
            elseif t(end,end)>60 %minute
                fc=60;
                tname='Timescale (minute)';
            else %second
                fc=1;
                tname='Timescale (second)';
            end
            %define rate unit----------------------------------------------------
            if israte
                if  mean(rate,'omitnan')>1 %°C/second
                    fc_rate=1;
                    unitname_rate='/second';
                elseif mean(rate,'omitnan')*60>1 %°C/minute
                    fc_rate=60;
                    unitname_rate='/minute';
                elseif mean(rate,'omitnan')*3600>1 %°C/hour
                    fc_rate=3600;
                    unitname_rate='/hour';
                elseif mean(rate,'omitnan')*(24*3600)>1 %°C/day
                    fc_rate=24*3600;
                    unitname_rate='/day';
                elseif mean(rate,'omitnan')*(365*24*3600)>1 %°C/year
                    fc_rate=365*24*3600;
                    unitname_rate='/year';
                elseif mean(rate,'omitnan')*(1e3*365*24*3600)>1 %°C/kyr
                    fc_rate=1e3*365*24*3600;
                    unitname_rate='/kyr';
                else %if mean(rate,'omitnan')*(1e6*365*24*3600)>1 %°C/myr
                    fc_rate=1e6*365*24*3600;
                    unitname_rate='/myr';
                end
                switch coolingpath
                    case 'linear cooling'
                        unitname_rate=['°C',unitname_rate];
                    case 'exponential cooling'
                        unitname_rate=['1',unitname_rate];
                    case 'parabolic cooling'
                        if  mean(rate,'omitnan')>1 %°C/second
                            fc_rate=1;
                            unitname_rate='/second';
                        elseif mean(rate,'omitnan')*(60^2)>1 %°C/minute
                            fc_rate=60^2;
                            unitname_rate='/minute';
                        elseif mean(rate,'omitnan')*(3600^2)>1 %°C/hour
                            fc_rate=3600^2;
                            unitname_rate='/hour';
                        elseif mean(rate,'omitnan')*(24*3600)^2>1 %°C/day
                            fc_rate=(24*3600)^2;
                            unitname_rate='/day';
                        elseif mean(rate,'omitnan')*(365*24*3600)^2>1 %°C/year
                            fc_rate=(365*24*3600)^2;
                            unitname_rate='/year';
                        elseif mean(rate,'omitnan')*(1e3*365*24*3600)^2>1 %°C/kyr
                            fc_rate=(1e3*365*24*3600)^2;
                            unitname_rate='/kyr';
                        else %if mean(rate,'omitnan')*(1e6*365*24*3600)^2>1 %°C/myr
                            fc_rate=(1e6*365*24*3600)^2;
                            unitname_rate='/myr';
                        end
                        unitname_rate=['°C',unitname_rate,'{^2}'];
                end
                ratename=['Cooling rate (',unitname_rate,')'];
                rate=rate*fc_rate;
                eb1_r=erate(:,1).*rate/100; %2 sigma absolute error
                eb2_r=erate(:,2).*rate/100;
            end

            t=t/fc;
            startindex=regexp(tname, '(');
            endindex=regexp(tname, ')');
            unitname=tname(startindex+1:endindex-1);
            eb1=et(:,1).*t/100; %2 sigma absolute error
            eb2=et(:,2).*t/100;
            T=T-273.15; %unit Celsius

            %plot timescale------------------------------------------------------
            if length(t)==1
                if ~israte
                    figure('Name', 'Timescale')
                    errorbar(1, t, eb1, eb2, 'Marker','s', 'LineStyle', 'none');
                    ylabel(tname);
                    xticks([])
                    title(['Timescale = ', num2str(t, '%.1f'), '_{', num2str(eb1, '%.1f'), '}', '^{+', num2str(eb2, '%.1f'), '} ', unitname]);
                else
                    figure('Name', 'Cooling rate vs. Timescale')
                    errorbar(rate, t, eb1, eb2, eb1_r, eb2_r, 'Marker','s', 'LineStyle', 'none');
                    ylabel(tname);
                    xlabel(ratename)
                    title(['Timescale = ', num2str(t, '%.1f'), '_{', num2str(eb1, '%.1f'), '}', '^{+', num2str(eb2, '%.1f'), '} ', unitname, newline, ...
                        'Cooling rate = ', num2str(rate, '%.1f'), '_{', num2str(eb1_r, '%.1f'), '}', '^{+', num2str(eb2_r, '%.1f'), '} ', unitname_rate]);
                end

                if Dtype
                    T_t(:,1)=T_t(:,1); %unit Celsius
                    T_t(:,2)=T_t(:,2)/fc;
                    x=T_t(:,1); y=T_t(:,2);
                    if israte
                        x=T_rate(:,2)*fc_rate;
                    end
                    x(isnan(y))=[]; y(isnan(y))=[];
                    y(isnan(x))=[]; x(isnan(x))=[];
                    if isempty(x)
                        uialert(app.DiffuserGUI,'Cannot calculate a timescale using current parameters','');
                        return
                    end
                    if eT>0 || israte
                        outlier1=exp(log(t)+(log(t+eb2)-log(t))*1.5); %filter with 3 sigma
                        outlier2=exp(log(t)-(log(t)-log(t-eb2))*1.5); %filter with 3 sigma
                        x(y>outlier1)=[]; y(y>outlier1)=[];
                        x(y<outlier2)=[]; y(y<outlier2)=[];
                        if ~israte
                            figure('Name', 'Distributions of temperature and diffusion timescale')
                        else
                            figure('Name', 'Distributions of cooling rate and diffusion timescale')
                        end
                        h=scatterhist(x,y,'Direction','out','Marker','none');
                        hold on
                        c = ksdensity([x,y], [x,y]);
                        cmp = [208	209	230
                            166	189	219
                            116	169	207
                            54	144	192
                            5	112	176
                            4	90	141
                            2	56	88
                            ]/255;
                        colormap(cmp)
                        try
                            Gs = round(interp1(linspace(min(c),max(c),size(cmp,1)),1:size(cmp,1),c));
                            colors = cmp(Gs,:); % make RGB matrix from scaled.
                            scatter(h(1),x, y, 30, colors,'filled');
                        catch
                            scatter(h(1),x, y, 'o');
                        end
                        hold off
                        if ~israte
                            h(1).XLabel.String='Temperature (\circC)';
                            h(1).YLabel.String=tname;
                            title(['Timescale = ', num2str(t, '%.1f'), '_{', num2str(eb1, '%.1f'), '}', '^{+', num2str(eb2, '%.1f'), '} ', unitname]);
                        else
                            h(1).XLabel.String=ratename;
                            h(1).YLabel.String=tname;
                            title(['Timescale = ', num2str(t, '%.1f'), '_{', num2str(eb1, '%.1f'), '}', '^{+', num2str(eb2, '%.1f'), '} ', unitname, ', ', ...
                        'Cooling rate = ', num2str(rate, '%.1f'), '_{', num2str(eb1_r, '%.1f'), '}', '^{+', num2str(eb2_r, '%.1f'), '} ', unitname_rate]);
                        end
                    else
                        figure('Name', 'Distribution of diffusion timescale')
                        histogram(y);
                        xlabel(tname)
                        ylabel('Number')
                        text(0.98,0.9,['Temperature = ', num2str(T), ' \circC'],'Units','normalized','HorizontalAlignment',"right")
                        title(['Timescale = ', num2str(t, '%.1f'), '_{', num2str(eb1, '%.1f'), '}', '^{+', num2str(eb2, '%.1f'), '} ', unitname]);
                    end

                    %plot error contribution
                    if isempty(error_contribution)
                        uialert(app.DiffuserGUI,'Trials are not enough to estimate ln[t] uncertainty budget','')
                    else
                        figure('Name', 'Uncertainty budget')
                        title('Uncertainty budget')
                        if ~israte
                            bar(error_contribution,'BarWidth',0.4)
                            text(0.98,0.9,['Curve fitting = ', num2str(error_contribution(1), '%.1f'), '%', newline, ...
                                'Temperature = ', num2str(error_contribution(2), '%.1f'), '%', newline,...
                                'Diffusivity = ', num2str(error_contribution(3), '%.1f'), '%'],...
                                'Units','normalized','HorizontalAlignment',"right")
                            ylabel('ln[t] uncertainty budget (%)')
                            legend('off')
                        else
                            if sum(error_contribution(1,:))==0
                                uialert(app.DiffuserGUI,'Trials are not enough to estimate ln[t] uncertainty budget','')
                            elseif size(error_contribution,1)<2 || sum(error_contribution(2,:))==0
                                uialert(app.DiffuserGUI,'Trials are not enough to estimate ln[rate] uncertainty budget','')
                            else
                                bar([1,2,3],error_contribution)
                                ylabel('Uncertainty budget (%)')
                                legend({'ln[t]','ln[rate]'})
                            end
                        end
                        xticklabels({'Curve fitting','Temperature','Diffusivity'})
                        ylim([0,100])
                    end
                end
            else %plot multiple timescales when no flat peak/trough in profiles I-L-----------------------------------------------
                figure('Name', 'Timescale')
                errorbar(C_data, t, eb1, eb2, 'Marker','s', 'LineStyle', 'none');
                xlabel('Assumed composition of the initial flat peak/trough')
                ylabel(tname);
                dx=diff(C_data);
                xlim([min(C_data)-dx(1), max(C_data)+dx(1)]);
                title(['Timescale ranges from ', num2str(t(1), '%.1f'), '_{', num2str(eb1(1), '%.1f'), '}', '^{+', num2str(eb2(1), '%.1f'), '} to ',...
                    num2str(t(end), '%.1f'), '_{', num2str(eb1(end), '%.1f'), '}', '^{', num2str(eb2(end), '%.1f'), '} ', unitname]);
                if israte
                    figure('Name', 'Cooling rate')
                    errorbar(C_data, rate, eb1_r, eb2_r, 'Marker','s', 'LineStyle', 'none');
                    xlabel('Assumed composition of the initial flat peak/trough')
                    ylabel(ratename);
                    dx=diff(C_data);
                    xlim([min(C_data)-dx(1), max(C_data)+dx(1)]);
                    title(['Cooling rate ranges from ', num2str(rate(1), '%.1f'), '_{', num2str(eb1_r(1), '%.1f'), '}', '^{+', num2str(eb2_r(1), '%.1f'), '} to ',...
                        num2str(rate(end), '%.1f'), '_{', num2str(eb1_r(end), '%.1f'), '}', '^{', num2str(eb2_r(end), '%.1f'), '} ', unitname_rate]);
                end
            end

            %write in file-------------------------------------------------
            if ~israte
                Title=[app.outputname,{tname, '-2s', '+2s'}];
                outputdata=num2cell([app.output, t, eb1, eb2]);
            else
                Title=[app.outputname,{tname, '-2s', '+2s', ratename, '-2s', '+2s'}];
                outputdata=num2cell([app.output, t, eb1, eb2, rate, eb1_r, eb2_r]);
            end

            filename=['t result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM')];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    if verLessThan('matlab','9.10')
                        xlswrite(fullfile(PathName,FileName),[Title;outputdata]);
                    else
                        writecell([Title;outputdata],fullfile(PathName,FileName),'AutoFitWidth', 0);
                    end
                otherwise
                    return
            end
        end

        % Value changed function: 
        % AflatpeaktroughcanbeseeninprofileILCheckBox
        function AflatpeaktroughcanbeseeninprofileILCheckBoxValueChanged(app, event)
            value = app.AflatpeaktroughcanbeseeninprofileILCheckBox.Value;
            selectedButton = app.DiffusionprofileButtonGroup.SelectedObject;
            profiletype = selectedButton.Text;
            if ~value
                app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable='on';
                app.minEditField.Enable='on';
                app.minEditFieldLabel.Enable='on';
                app.maxEditField.Enable='on';
                app.maxEditFieldLabel.Enable='on';
                app.steplengthEditField.Enable='on';
                app.steplengthEditFieldLabel.Enable='on';

                if contains(profiletype, {'I','K'}) %high value in the region
                    app.FixedC2CheckBox.Value=0;
                    app.FixedC2CheckBox.Enable='off';
                    app.C2EditField.Enable='off';
                else
                    app.FixedC1CheckBox.Value=0;
                    app.FixedC1CheckBox.Enable='off';
                    app.C1EditField.Enable='off';
                end
            else
                app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable='off';
                app.minEditField.Enable='off';
                app.minEditFieldLabel.Enable='off';
                app.maxEditField.Enable='off';
                app.maxEditFieldLabel.Enable='off';
                app.steplengthEditField.Enable='off';
                app.steplengthEditFieldLabel.Enable='off';

                if contains(profiletype, {'I','K'}) %high value in the region
                    app.FixedC2CheckBox.Enable='on';
                    app.C2EditField.Enable='on';
                else
                    app.FixedC1CheckBox.Enable='on';
                    app.C1EditField.Enable='on';
                end
            end
        end

        % Button pushed function: SeeC1C2andC3definitionsButton
        function SeeC1C2andC3definitionsButtonPushed(app, event)
            str='Diffusion profile options.jpg';
            if exist(str, 'file')
                figure('Name', 'Diffusion profile options')
                imshow(str)
            else
                uialert(app.DiffuserGUI,"Cannot find the orginal file 'Diffusion profile options.jpg'",'')
                return
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.UseArrheniusequationButton.Value
                app.UITable2.ColumnName={'ln[D0] (m2/s)','2σ ln[D0]','Ea (kJ/mol)','2σ Ea','Covariance (ln[D0],Ea)'};
                mineral = app.ExamplesDropDown.Value;
                mineral_site=find(strcmp(app.name(:,1),mineral));
                elementlist=app.name(mineral_site,2);
                element=app.Element.Value;
                element_site=mineral_site(strcmp(elementlist,element));
                referencelist=app.name(element_site,3);
                reference=app.Reference.Value;
                reference_site=element_site(strcmp(referencelist,reference));
                app.UITable2.Data=app.DiffCoef(reference_site(1),:);

                app.ExamplesDropDown.Enable='on';
                app.ExamplesDropDownLabel.Enable='on';
                app.Element.Enable='on';
                app.Reference.Enable='on';
                app.CalculatetwithD0andEaerrorsCheckBox.Enable='on';

                app.InitialtemperatureEditField.Enable='on';
                app.TemperatureerrorEditField.Enable='on';
                app.TrialsforMonteCarloEditField.Enable='on';
                app.OptionsDropDown.Enable='on';
                app.UITable3.Enable='on';
                app.CoolingrateisknownCheckBox.Enable='on';
            end
            if app.UseaconstantDButton.Value
                app.UITable2.ColumnName={'D (m2/s)','2σ D'};
                app.UITable2.Data=[2e-22,1e-23];

                app.ExamplesDropDown.Enable='off';
                app.ExamplesDropDownLabel.Enable='off';
                app.Element.Enable='off';
                app.Reference.Enable='off';
                app.CalculatetwithD0andEaerrorsCheckBox.Enable='off';

                app.InitialtemperatureEditField.Enable='off';
                app.TemperatureerrorEditField.Enable='off';
                app.TrialsforMonteCarloEditField.Enable='off';
                app.OptionsDropDown.Enable='off';
                app.UITable3.Enable='off';
                app.CoolingrateisknownCheckBox.Enable='off';
            end
        end

        % Selection changed function: BeamshapeButtonGroup
        function BeamshapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.BeamshapeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Circular'
                    app.DiameterEditField.Enable='on';
                    app.DiameterEditFieldLabel.Enable='on';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case 'Rectangle'
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='on';
                    app.WidthEditFieldLabel.Enable='on';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case {'Gauss','Lorentz'}
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='on';
                    app.FWHMEditFieldLabel.Enable='on';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case 'Voigt'
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='on';
                    app.FWHMGaussEditFieldLabel.Enable='on';
                    app.FWHMLorentzEditField.Enable='on';
                    app.FWHMLorentzEditFieldLabel.Enable='on';
            end
        end

        % Button pushed function: SelectfileButton_2
        function SelectfileButton_2Pushed(app, event)
            [filename, pathname]=uigetfile({'*.xlsx;*.xls;*.csv;*.txt'},'Select a file','MultiSelect','off');
            if ~pathname
                return
            end
            str=fullfile(pathname,filename);
            [~,~,extension]=fileparts(filename);
            switch extension
                case {'.txt','.csv'}
                    tmp=readmatrix(str);
                    if size(tmp,2)>2
                        prompt={'Temperature column (A,B,C etc)','ln[D] column','Uncertainty column (optional)'};
                        dlgtitle='Input';
                        dims=[1 35];
                        definput={'A','B','C'};
                        answer=inputdlg(prompt,dlgtitle,dims,definput);
                        data0(:,1)=readmatrix(str,'Range',strcat(answer{1},':',answer{1}));
                        data0(:,2)=readmatrix(str,'Range',strcat(answer{2},':',answer{2}));
                        if ~isempty(answer{3})
                            data0(:,3)=readmatrix(str,'Range',strcat(answer{3},':',answer{3}));
                        end
                        tmp=data0;
                    end
                case {'.xlsx','.xls'}
                    sheets = sheetnames(str);
                    cs=length(sheets);
                    if cs==1
                        sheet=sheets{1};
                        v=1;
                    else
                        [st,v]=listdlg('PromptString','Select a sheet','SelectionMode','single','ListString',sheets);
                        sheet=sheets{st};
                    end
                    if v
                        tmp=readmatrix(str,'Sheet',sheet);
                        if size(tmp,2)>2
                            prompt={'Temperature column (A,B,C etc)','ln[D] column','Uncertainty column (optional)'};
                            dlgtitle='Input';
                            dims=[1 35];
                            definput={'A','B','C'};
                            answer=inputdlg(prompt,dlgtitle,dims,definput);
                            data0(:,1)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{1},':',answer{1}));
                            data0(:,2)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{2},':',answer{2}));
                            if ~isempty(answer{3})
                                data0(:,3)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{3},':',answer{3}));
                            end
                            tmp=data0;
                        end
                    end
                otherwise
                    return
            end

            % delete the first non-numeric rows
            for i=1:size(tmp,1)
                if  isnan(tmp(1,:))
                    tmp(1,:)=[];
                else
                    break
                end
            end

            app.UITable4.Data=tmp;
            app.UITable4.ColumnFormat={'numeric'};
        end

        % Button pushed function: ClipboardButton_2
        function ClipboardButton_2Pushed(app, event)
            %read clipboard data
            try
                tmp=getclipdata(1);
            catch
                uialert(app.DiffuserGUI, 'Fail to use clipboard with java on this computer','')
                return
            end
            col=size(tmp,2);
            if col~=2 && col~=3
                uialert(app.DiffuserGUI,'Input data should have 2 or 3 columns','')
                return
            end
            app.UITable4.Data=tmp;
            app.UITable4.ColumnFormat={'numeric'};
        end

        % Button pushed function: LoadexampleButton
        function LoadexampleButtonPushed(app, event)
            app.UITable4.Data=[900	-50.61082034	1.312473503
                900	-50.54174279	1.151292546
                1000	-49.09111418	0.944059888
                1000	-48.26218355	0.299336062
                1100	-45.61421069	0.575646273
                1100	-47.17996856	0.944059888
                1100	-46.21288282	1.197344248
                1200	-44.69317666	0.621697975
                1200	-46.30498622	0.73682723
                1200	-44.94646102	1.450628609
                1300	-42.92018613	0.851956484
                1300	-41.35442827	2.417714348
                1400	-41.65376433	0.391439466
                1400	-41.9991521	0.299336062
                1400	-41.76889359	0.437491168
                1490	-39.44328264	0.299336062
                1200	-46.71945154	2.026274882
                1200	-46.90365834	1.427602758
                1300	-45.52210729	1.565757863
                1300	-45.24579708	0.99011159
                ];
        end

        % Button pushed function: CleartableButton_2
        function CleartableButton_2Pushed(app, event)
            app.UITable4.Data=repmat({[]},10,3);
        end

        % Button pushed function: CurvefitButton
        function CurvefitButtonPushed(app, event)
            DTdata=app.UITable4.Data;
            T=DTdata(:,1);
            x=1./(T+273.15); %1/K
            y=DTdata(:,2); %ln[D]
            if size(DTdata,2)==3
                ey=DTdata(:,3); %uncertainty
            else
                ey=ones(length(x),1);
            end
            w=1./ey.^2;
            [xData, yData, weights] = prepareCurveData( x, y, w );
            ft = fittype( 'poly1' );
            opts = fitoptions( 'Method', 'LinearLeastSquares' );
            opts.Weights = weights;
            [fitresult, gof, fitoutput] = fit( xData, yData, ft, opts ); %y=p1*x+p2
            p=coeffvalues(fitresult);
            ci=confint(fitresult);
            freedom=length(x)-numcoeffs(fitresult);
            se(1)=(ci(2,1)-p(1))/tinv(0.975,freedom); %convert 95%c.l. to 1 sigma
            se(2)=(ci(2,2)-p(2))/tinv(0.975,freedom); %convert 95%c.l. to 1 sigma
            J=fitoutput.Jacobian;
            RMSE=gof.rmse;
            MSE=RMSE.^2;
            Cov=inv(J'*J)*MSE; %covariance matrix, %covariance between ln(D0) and -E/R
            %D0*exp(-E/RT) --------------------------------
            E=p(1)*-8.314/1000; %kJ/mol
            eE=se(1)*8.314/1000; %1 sigma
            lnD0=p(2);
            elnD0=se(2); %1 sigma
            Cov_lnD0_E=Cov(1,2)*-8.314/1000; %covariance between ln(D0) and E
            %Plot--------------------------------------------
            figure;
            h = plot( fitresult, xData, yData );
            h(1).Marker='o';
            h(2).LineWidth=1;
            xlabel( '1/T (1/K)' );
            ylabel( 'ln[D]' );
            legend({'raw data',['R^{2} = ',num2str(gof.rsquare,2),...
                newline,'E_{a} = ',num2str(E,'%.1f'), ' ± ',num2str(eE*2,'%.1f'),' (2s)',...
                newline,'ln[D_{0}] = ',num2str(lnD0,'%.2f'), ' ± ',num2str(elnD0*2,'%.2f'),' (2s)',...
                newline,'Cov = ', num2str(Cov_lnD0_E,'%.2f')]})
            grid on
            app.UITable5.Data=[lnD0,elnD0*2,E,eE*2,Cov_lnD0_E];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Title={'ln[D0] (m2/s)','2s ln[D0]','Ea (kJ/mol)','2s Ea','Covariance (ln[D0],Ea)'};
            outputdata=num2cell(app.UITable5.Data);

            filename=['D-T curve fit result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM')];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    if verLessThan('matlab','9.10')
                        xlswrite(fullfile(PathName,FileName),[Title;outputdata]);
                    else
                        writecell([Title;outputdata],fullfile(PathName,FileName),'AutoFitWidth', 0);
                    end
                otherwise
                    return
            end
        end

        % Value changed function: CoolingrateisknownCheckBox
        function CoolingrateisknownCheckBoxValueChanged(app, event)
            value = app.CoolingrateisknownCheckBox.Value;
            if value
                app.UITable3.Enable="on";
                uialert(app.DiffuserGUI,['Please enter the cooling rate in the table below.', newline, ...
                    'Diffuser will then calculate the timescale at which the modeled profile best fits the measured one.', newline, ...
                    'Note that this does not indicate the diffusion has stopped at that time.', newline, ...
                    'To obtain the specific cooling rate at which diffusion just ceases at the measured profile, allow Diffuser to calculate it automatically.'], ...
                    'Notice', 'Icon','info')
            else
                app.UITable3.Enable="off";
                uialert(app.DiffuserGUI,'With no cooling rate provided, Diffuser will automatically determine it and calculate the corresponding shortest timescale at which diffusion ceases at the measured profile.',...
                    'Notice', 'Icon','info')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiffuserGUI = uifigure('Visible', 'off');
            app.DiffuserGUI.Name = 'Diffuser';
            app.TabGroup = uitabgroup(app.DiffuserGUI);
            app.ImportdataTab = uitab(app.TabGroup);
            app.ImportdataTab.Title = '1. Import data';
            app.UIAxes = uiaxes(app.ImportdataTab);
            title(app.UIAxes, 'Diffusion data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'C')
            zlabel(app.UIAxes, 'Z')
            app.SelectfileButton = uibutton(app.ImportdataTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.CleartableButton = uibutton(app.ImportdataTab, 'push');
            app.CleartableButton.ButtonPushedFcn = createCallbackFcn(app, @CleartableButtonPushed, true);
            app.CleartableButton.Text = 'Clear table';
            app.UITable = uitable(app.ImportdataTab);
            app.UITable.ColumnName = {'Distance (X)'; 'Composition (C)'; 'Uncertainty of C'};
            app.UITable.RowName = {};
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.ClipboardButton = uibutton(app.ImportdataTab, 'push');
            app.ClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @ClipboardButtonPushed, true);
            app.ClipboardButton.Tooltip = {'Import data from clipboard.'};
            app.ClipboardButton.Text = 'Clipboard';
            app.RandomButton = uibutton(app.ImportdataTab, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.text11 = uilabel(app.ImportdataTab);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Diffuser';
            app.Image_1 = uiimage(app.ImportdataTab);
            app.Image_1.ImageSource = 'Diffuser.png';
            app.DeconvolutionoptionalTab = uitab(app.TabGroup);
            app.DeconvolutionoptionalTab.Title = 'Deconvolution (optional)';
            app.BeamshapeButtonGroup = uibuttongroup(app.DeconvolutionoptionalTab);
            app.BeamshapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BeamshapeButtonGroupSelectionChanged, true);
            app.BeamshapeButtonGroup.Title = 'Beam shape';
            app.CircularButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.CircularButton.Icon = 'circular.jpg';
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangleButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.RectangleButton.Icon = 'rectangle.jpg';
            app.RectangleButton.Text = 'Rectangle';
            app.GaussButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.GaussButton.Icon = 'gauss.jpg';
            app.GaussButton.Text = 'Gauss';
            app.LorentzButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.LorentzButton.Icon = 'lorentz.jpg';
            app.LorentzButton.Text = 'Lorentz';
            app.VoigtButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.VoigtButton.Icon = 'voigt.jpg';
            app.VoigtButton.Text = 'Voigt';
            app.DeconvoluteanalyticalbeameffectsCheckBox = uicheckbox(app.DeconvolutionoptionalTab);
            app.DeconvoluteanalyticalbeameffectsCheckBox.Text = 'Deconvolute analytical beam effects';
            app.DeconvolutionismodifiedfromLabel = uilabel(app.DeconvolutionoptionalTab);
            app.DeconvolutionismodifiedfromLabel.Text = 'Deconvolution is modified from';
            app.BeamsizePanel = uipanel(app.DeconvolutionoptionalTab);
            app.BeamsizePanel.Title = 'Beam size';
            app.fullwidthathalfmaximiumLabel = uilabel(app.BeamsizePanel);
            app.fullwidthathalfmaximiumLabel.Text = 'full width at half maximium ';
            app.DiameterEditFieldLabel = uilabel(app.BeamsizePanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.DiameterEditField.Limits = [0 Inf];
            app.WidthEditFieldLabel = uilabel(app.BeamsizePanel);
            app.WidthEditFieldLabel.Enable = 'off';
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.Enable = 'off';
            app.FWHMEditFieldLabel = uilabel(app.BeamsizePanel);
            app.FWHMEditFieldLabel.Enable = 'off';
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.FWHMEditField.Limits = [0 Inf];
            app.FWHMEditField.Enable = 'off';
            app.FWHMGaussEditFieldLabel = uilabel(app.BeamsizePanel);
            app.FWHMGaussEditFieldLabel.Enable = 'off';
            app.FWHMGaussEditFieldLabel.Text = 'FWHM Gauss';
            app.FWHMGaussEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.FWHMGaussEditField.Limits = [0 Inf];
            app.FWHMGaussEditField.Enable = 'off';
            app.FWHMLorentzEditFieldLabel = uilabel(app.BeamsizePanel);
            app.FWHMLorentzEditFieldLabel.Enable = 'off';
            app.FWHMLorentzEditFieldLabel.Text = 'FWHM Lorentz';
            app.FWHMLorentzEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.FWHMLorentzEditField.Limits = [0 Inf];
            app.FWHMLorentzEditField.Enable = 'off';
            app.UnitDropDownLabel = uilabel(app.BeamsizePanel);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.BeamsizePanel);
            app.UnitDropDown.Items = {'nm', 'um', 'mm', 'm'};
            app.UnitDropDown.Value = 'um';
            app.Image_2 = uiimage(app.DeconvolutionoptionalTab);
            app.Image_2.ImageSource = 'Diffuser.png';
            app.Hyperlink = uihyperlink(app.DeconvolutionoptionalTab);
            app.Hyperlink.URL = 'https://doi.org/10.1371/journal.pone.0241788';
            app.Hyperlink.Text = 'Jollands, 2020, PLOS ONE';
            app.DiffusionmodelingTab = uitab(app.TabGroup);
            app.DiffusionmodelingTab.Title = '2. Diffusion modeling';
            app.DiffusionprofileButtonGroup = uibuttongroup(app.DiffusionmodelingTab);
            app.DiffusionprofileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DiffusionprofileButtonGroupSelectionChanged, true);
            app.DiffusionprofileButtonGroup.Title = 'Diffusion profile';
            app.AButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.AButton.Tag = 'A';
            app.AButton.Icon = 'A.jpg';
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.BButton.Tag = 'B';
            app.BButton.Icon = 'B.jpg';
            app.BButton.Text = 'B';
            app.CButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.CButton.Tag = 'C';
            app.CButton.Icon = 'C.jpg';
            app.CButton.Text = 'C';
            app.DButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.DButton.Tag = 'D';
            app.DButton.Icon = 'D.jpg';
            app.DButton.Text = 'D';
            app.EButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.EButton.Tag = 'E';
            app.EButton.Icon = 'E.jpg';
            app.EButton.Text = 'E';
            app.FButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.FButton.Tag = 'F';
            app.FButton.Icon = 'F.jpg';
            app.FButton.Text = 'F';
            app.GButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.GButton.Tag = 'G';
            app.GButton.Icon = 'G.jpg';
            app.GButton.Text = 'G';
            app.HButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.HButton.Tag = 'H';
            app.HButton.Icon = 'H.jpg';
            app.HButton.Text = 'H';
            app.IButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.IButton.Tag = 'I';
            app.IButton.Icon = 'I.jpg';
            app.IButton.Text = 'I';
            app.JButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.JButton.Tag = 'J';
            app.JButton.Icon = 'J.jpg';
            app.JButton.Text = 'J';
            app.KButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.KButton.Tag = 'K';
            app.KButton.Icon = 'K.jpg';
            app.KButton.Text = 'K';
            app.LButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.LButton.Tag = 'L';
            app.LButton.Icon = 'L.jpg';
            app.LButton.Text = 'L';
            app.InitialcompositionsoptionalPanel = uipanel(app.DiffusionmodelingTab);
            app.InitialcompositionsoptionalPanel.Title = 'Initial compositions (optional)';
            app.minCLabel = uilabel(app.InitialcompositionsoptionalPanel);
            app.minCLabel.Text = 'min C';
            app.maxCLabel = uilabel(app.InitialcompositionsoptionalPanel);
            app.maxCLabel.Text = 'max C';
            app.mediumCusedinprofileKLLabel = uilabel(app.InitialcompositionsoptionalPanel);
            app.mediumCusedinprofileKLLabel.Text = 'medium C used in profile K-L';
            app.FixedC1CheckBox = uicheckbox(app.InitialcompositionsoptionalPanel);
            app.FixedC1CheckBox.Text = 'Fixed C1';
            app.AflatpeaktroughcanbeseeninprofileILCheckBox = uicheckbox(app.InitialcompositionsoptionalPanel);
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.ValueChangedFcn = createCallbackFcn(app, @AflatpeaktroughcanbeseeninprofileILCheckBoxValueChanged, true);
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable = 'off';
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Text = 'A flat peak/trough can be seen in profile I-L';
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Value = true;
            app.C1EditField = uieditfield(app.InitialcompositionsoptionalPanel, 'numeric');
            app.C1EditField.Limits = [0 Inf];
            app.C1EditField.ValueDisplayFormat = '%.2f';
            app.C2EditField = uieditfield(app.InitialcompositionsoptionalPanel, 'numeric');
            app.C2EditField.Limits = [0 Inf];
            app.C2EditField.ValueDisplayFormat = '%.2f';
            app.C2EditField.Value = 100;
            app.C3EditField = uieditfield(app.InitialcompositionsoptionalPanel, 'numeric');
            app.C3EditField.Limits = [0 Inf];
            app.C3EditField.ValueDisplayFormat = '%.2f';
            app.C3EditField.Enable = 'off';
            app.FixedC2CheckBox = uicheckbox(app.InitialcompositionsoptionalPanel);
            app.FixedC2CheckBox.Text = 'Fixed C2';
            app.FixedC3CheckBox = uicheckbox(app.InitialcompositionsoptionalPanel);
            app.FixedC3CheckBox.Text = 'Fixed C3';
            app.minEditFieldLabel = uilabel(app.InitialcompositionsoptionalPanel);
            app.minEditFieldLabel.Enable = 'off';
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.InitialcompositionsoptionalPanel, 'numeric');
            app.minEditField.ValueDisplayFormat = '%.2f';
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.InitialcompositionsoptionalPanel);
            app.maxEditFieldLabel.Enable = 'off';
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.InitialcompositionsoptionalPanel, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%.2f';
            app.maxEditField.Enable = 'off';
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel = uilabel(app.InitialcompositionsoptionalPanel);
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable = 'off';
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Text = 'Input the C range of the initial flat peak/trough in profile I-L:';
            app.steplengthEditFieldLabel = uilabel(app.InitialcompositionsoptionalPanel);
            app.steplengthEditFieldLabel.Enable = 'off';
            app.steplengthEditFieldLabel.Text = 'step length';
            app.steplengthEditField = uieditfield(app.InitialcompositionsoptionalPanel, 'numeric');
            app.steplengthEditField.ValueDisplayFormat = '%.2f';
            app.steplengthEditField.Enable = 'off';
            app.SeeC1C2andC3definitionsButton = uibutton(app.InitialcompositionsoptionalPanel, 'push');
            app.SeeC1C2andC3definitionsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeC1C2andC3definitionsButtonPushed, true);
            app.SeeC1C2andC3definitionsButton.Text = 'See C1,C2,and C3 definitions';
            app.StartmodelingButton = uibutton(app.DiffusionmodelingTab, 'push');
            app.StartmodelingButton.ButtonPushedFcn = createCallbackFcn(app, @StartmodelingButtonPushed, true);
            app.StartmodelingButton.Text = 'Start modeling';
            app.CalculateDtButton = uibutton(app.DiffusionmodelingTab, 'push');
            app.CalculateDtButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDtButtonPushed, true);
            app.CalculateDtButton.Text = 'Calculate Dt';
            app.XunitDropDownLabel = uilabel(app.DiffusionmodelingTab);
            app.XunitDropDownLabel.Text = 'X unit';
            app.XunitDropDown = uidropdown(app.DiffusionmodelingTab);
            app.XunitDropDown.Items = {'nm', 'um', 'mm', 'm'};
            app.XunitDropDown.Value = 'um';
            app.Image_3 = uiimage(app.DiffusionmodelingTab);
            app.Image_3.ImageSource = 'Diffuser.png';
            app.TimecalculationTab = uitab(app.TabGroup);
            app.TimecalculationTab.Title = '3. Time calculation';
            app.sigmaLabel = uilabel(app.TimecalculationTab);
            app.sigmaLabel.Text = '2 sigma';
            app.Label = uilabel(app.TimecalculationTab);
            app.Label.Text = '(℃)';
            app.CalculatetButton = uibutton(app.TimecalculationTab, 'push');
            app.CalculatetButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatetButtonPushed, true);
            app.CalculatetButton.Text = 'Calculate t';
            app.CoolingpathPanel = uipanel(app.TimecalculationTab);
            app.CoolingpathPanel.Title = 'Cooling path';
            app.OptionsDropDownLabel = uilabel(app.CoolingpathPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.CoolingpathPanel);
            app.OptionsDropDown.Items = {'isothermal', 'linear cooling', 'exponential cooling', 'parabolic cooling'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'isothermal';
            app.UITable3 = uitable(app.CoolingpathPanel);
            app.UITable3.ColumnName = {'Constant (A)'; 'Unit of A'; 'Equation form'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [false true false];
            app.CoolingrateisknownCheckBox = uicheckbox(app.CoolingpathPanel);
            app.CoolingrateisknownCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoolingrateisknownCheckBoxValueChanged, true);
            app.CoolingrateisknownCheckBox.Enable = 'off';
            app.CoolingrateisknownCheckBox.Text = 'Cooling rate is known';
            app.CoolingrateisknownCheckBox.Value = true;
            app.TemperatureerrorEditFieldLabel = uilabel(app.TimecalculationTab);
            app.TemperatureerrorEditFieldLabel.Text = 'Temperature error';
            app.TemperatureerrorEditField = uieditfield(app.TimecalculationTab, 'numeric');
            app.TemperatureerrorEditField.Limits = [0 Inf];
            app.TemperatureerrorEditField.ValueDisplayFormat = '%.2f';
            app.TemperatureerrorEditField.Value = 20;
            app.TrialsforMonteCarloEditFieldLabel = uilabel(app.TimecalculationTab);
            app.TrialsforMonteCarloEditFieldLabel.Text = 'Trials for Monte Carlo';
            app.TrialsforMonteCarloEditField = uieditfield(app.TimecalculationTab, 'numeric');
            app.TrialsforMonteCarloEditField.ValueDisplayFormat = '%.0f';
            app.TrialsforMonteCarloEditField.Value = 1000;
            app.InitialtemperatureEditFieldLabel = uilabel(app.TimecalculationTab);
            app.InitialtemperatureEditFieldLabel.Text = 'Initial temperature';
            app.InitialtemperatureEditField = uieditfield(app.TimecalculationTab, 'numeric');
            app.InitialtemperatureEditField.ValueDisplayFormat = '%.2f';
            app.InitialtemperatureEditField.Value = 700;
            app.Image_4 = uiimage(app.TimecalculationTab);
            app.Image_4.ImageSource = 'Diffuser.png';
            app.DiffusionCoefficientDPanel = uipanel(app.TimecalculationTab);
            app.DiffusionCoefficientDPanel.Title = 'Diffusion Coefficient (D)';
            app.ExamplesDropDownLabel = uilabel(app.DiffusionCoefficientDPanel);
            app.ExamplesDropDownLabel.Text = 'Examples';
            app.ExamplesDropDown = uidropdown(app.DiffusionCoefficientDPanel);
            app.ExamplesDropDown.Items = {'Quartz Ti	Cherniak et al., 2007, Chem Geol', 'Option 2', 'Option 3', 'Option 4'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.ExamplesDropDown.Value = 'Quartz Ti	Cherniak et al., 2007, Chem Geol';
            app.UITable2 = uitable(app.DiffusionCoefficientDPanel);
            app.UITable2.ColumnName = {'ln[D0] (m2/s)'; '2σ ln[D0]'; 'Ea (kJ/mol)'; '2σ Ea'; 'Covariance (ln[D0],Ea)'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.ButtonGroup = uibuttongroup(app.DiffusionCoefficientDPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.UseArrheniusequationButton = uiradiobutton(app.ButtonGroup);
            app.UseArrheniusequationButton.Text = 'Use Arrhenius equation: D=D0*exp(-Ea/RT)';
            app.UseArrheniusequationButton.Value = true;
            app.UseaconstantDButton = uiradiobutton(app.ButtonGroup);
            app.UseaconstantDButton.Text = 'Use a constant D';
            app.Element = uidropdown(app.DiffusionCoefficientDPanel);
            app.Element.Items = {'Ti', 'Al'};
            app.Element.ValueChangedFcn = createCallbackFcn(app, @ElementValueChanged, true);
            app.Element.Value = 'Ti';
            app.Reference = uidropdown(app.DiffusionCoefficientDPanel);
            app.Reference.Items = {'Jollands et al. (2020)'};
            app.Reference.ValueChangedFcn = createCallbackFcn(app, @ReferenceValueChanged, true);
            app.Reference.Value = 'Jollands et al. (2020)';
            app.CalculatetwithD0andEaerrorsCheckBox = uicheckbox(app.DiffusionCoefficientDPanel);
            app.CalculatetwithD0andEaerrorsCheckBox.Text = 'Calculate t with D0 and Ea errors';
            app.DcalculationTab = uitab(app.TabGroup);
            app.DcalculationTab.Title = 'D calculation';
            app.DcalculationforexperimentalstudyPanel = uipanel(app.DcalculationTab);
            app.DcalculationforexperimentalstudyPanel.Title = 'D calculation (for experimental study)';
            app.sLabel = uilabel(app.DcalculationforexperimentalstudyPanel);
            app.sLabel.Text = '(s)';
            app.TimeEditFieldLabel = uilabel(app.DcalculationforexperimentalstudyPanel);
            app.TimeEditFieldLabel.Text = ' Time';
            app.TimeEditField = uieditfield(app.DcalculationforexperimentalstudyPanel, 'numeric');
            app.TimeEditField.Limits = [0 Inf];
            app.TimeEditField.ValueDisplayFormat = '%.0f';
            app.TimeEditField.Value = 1000;
            app.CalculateDButton = uibutton(app.DcalculationforexperimentalstudyPanel, 'push');
            app.CalculateDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDButtonPushed, true);
            app.CalculateDButton.Text = 'Calculate D';
            app.UITable4 = uitable(app.DcalculationTab);
            app.UITable4.ColumnName = {'T (℃)'; 'ln[D]'; 'Uncertainty of ln[D]'};
            app.UITable4.RowName = {};
            app.lnDTdataimportPanel = uipanel(app.DcalculationTab);
            app.lnDTdataimportPanel.Title = 'ln[D]-T data import';
            app.SelectfileButton_2 = uibutton(app.lnDTdataimportPanel, 'push');
            app.SelectfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButton_2Pushed, true);
            app.SelectfileButton_2.Text = 'Select file';
            app.ClipboardButton_2 = uibutton(app.lnDTdataimportPanel, 'push');
            app.ClipboardButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClipboardButton_2Pushed, true);
            app.ClipboardButton_2.Text = 'Clipboard';
            app.CleartableButton_2 = uibutton(app.lnDTdataimportPanel, 'push');
            app.CleartableButton_2.ButtonPushedFcn = createCallbackFcn(app, @CleartableButton_2Pushed, true);
            app.CleartableButton_2.Text = 'Clear table';
            app.LoadexampleButton = uibutton(app.lnDTdataimportPanel, 'push');
            app.LoadexampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexampleButtonPushed, true);
            app.LoadexampleButton.Text = 'Load example';
            app.UITable5 = uitable(app.DcalculationTab);
            app.UITable5.ColumnName = {'ln[D0]'; '2σ ln[D0]'; 'Ea'; '2σ Ea'; 'Covariance (ln[D0],Ea)'};
            app.UITable5.RowName = {};
            app.lnD1TcurvefittingPanel = uipanel(app.DcalculationTab);
            app.lnD1TcurvefittingPanel.Title = 'ln[D]-1/T curve fitting';
            app.CurvefitButton = uibutton(app.lnD1TcurvefittingPanel, 'push');
            app.CurvefitButton.ButtonPushedFcn = createCallbackFcn(app, @CurvefitButtonPushed, true);
            app.CurvefitButton.Text = 'Curve fit';
            app.SaveButton = uibutton(app.lnD1TcurvefittingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Image_5 = uiimage(app.DcalculationTab);
            app.Image_5.ImageSource = 'Diffuser.png';
            % Show the figure after all components are created
            app.DiffuserGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diffuser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiffuserGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiffuserGUI)
        end
    end
end